classdef Proyecto2DSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PreprocesamientoTab           matlab.ui.container.Tab
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ExtraccindecaractersticasTab  matlab.ui.container.Tab
        ClasificacinTab               matlab.ui.container.Tab
        EvaluacindedesempeoTab        matlab.ui.container.Tab
        IdentificacindesealPanel      matlab.ui.container.Panel
        SealnoseleccionadaLabel       matlab.ui.control.Label
        SeleccindesealPanel           matlab.ui.container.Panel
        IDsensorspeedamplificationtraceEditField  matlab.ui.control.EditField
        IDsensorspeedamplificationtraceEditFieldLabel  matlab.ui.control.Label
        IdentificarButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        signal % Señal capturada
        filtered_signal % Señal filtrada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IdentificarButton
        function IdentificarButtonPushed(app, event)
            IDsensorspeedamplificationtraceEditFieldValueChanged(app, event);
            % read_footstep_trace_data()
        end

        % Value changed function: IDsensorspeedamplificationtraceEditField
        function IDsensorspeedamplificationtraceEditFieldValueChanged(app, event)
            value = app.IDsensorspeedamplificationtraceEditField.Value;
            % Parse the input into numbers (comma-separated values)
            inputNumbers = str2double(split(value, ','));
            
            % Default values
            defaultValues = [8, 6, 10, 50, 40];
            
            % Validate the number of inputs
            if length(inputNumbers) == 5
                % Replace the default values with the input values
                defaultValues = inputNumbers;
            else
                % Show a warning if the input is not valid
                disp('Please enter exactly 5 comma-separated numbers.');
            end
            
            % Update any variables or UI elements with the new values
            % app.UpdatedValues = defaultValues; % Store updated values in the app object
            disp('Updated Values:');
            disp(defaultValues);
            
            addpath 'C:\Users\Daniel\OneDrive - correounivalle.edu.co\Univalle\09_Noveno_semestre\DSP\Data_Set_gait'
            app.signal = read_footstep_trace_data(defaultValues(1), defaultValues(2), ...
                defaultValues(3), defaultValues(4), defaultValues(5));

            app.filtered_signal = processing_stage_no_plot(app.signal);

            UIAxes2ButtonDown(app, event);
            UIAxesButtonDown(app, event);
            UIAxes4ButtonDown(app, event);
            UIAxes3ButtonDown(app, event);
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            signal_plot(app.UIAxes2, app.signal, 'Señal capturada', 'Tiempo', 'Amplitud');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            signal_plot(app.UIAxes, app.filtered_signal, 'Señal filtrada', 'Tiempo', 'Amplitud');
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            [frequency_response, frequency_bins] = frequency_domain(app.filtered_signal, 1000);
            plot(app.UIAxes4, frequency_bins, frequency_response);
            title(app.UIAxes4, 'Dominio de frecuencia');
            xlabel(app.UIAxes4, 'Hz');
            ylabel(app.UIAxes4, 'Amplitud');
            grid(app.UIAxes4, 'on');

            % Set the x-axis (frequency) range
            xlim(app.UIAxes4, [0, 150]); % Show only 0 to 200 Hz
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            [frequency_response, frequency_bins] = frequency_domain(app.signal, 1000);
            plot(app.UIAxes3, frequency_bins, frequency_response);
            title(app.UIAxes3, 'Dominio de frecuencia');
            xlabel(app.UIAxes3, 'Hz');
            ylabel(app.UIAxes3, 'Amplitud');
            grid(app.UIAxes3, 'on');

            % Set the x-axis (frequency) range
            xlim(app.UIAxes3, [0, 150]); % Show only 0 to 200 Hz
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SeleccindesealPanel = uipanel(app.UIFigure);
            app.SeleccindesealPanel.Title = 'Selección de señal';
            app.IdentificarButton = uibutton(app.SeleccindesealPanel, 'push');
            app.IdentificarButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificarButtonPushed, true);
            app.IdentificarButton.Text = 'Identificar';
            app.IDsensorspeedamplificationtraceEditFieldLabel = uilabel(app.SeleccindesealPanel);
            app.IDsensorspeedamplificationtraceEditFieldLabel.Text = 'ID, sensor, speed, amplification, trace';
            app.IDsensorspeedamplificationtraceEditField = uieditfield(app.SeleccindesealPanel, 'text');
            app.IDsensorspeedamplificationtraceEditField.ValueChangedFcn = createCallbackFcn(app, @IDsensorspeedamplificationtraceEditFieldValueChanged, true);
            app.IdentificacindesealPanel = uipanel(app.UIFigure);
            app.IdentificacindesealPanel.Title = 'Identificación de señal';
            app.SealnoseleccionadaLabel = uilabel(app.IdentificacindesealPanel);
            app.SealnoseleccionadaLabel.Text = 'Señal no seleccionada';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocesamientoTab = uitab(app.TabGroup);
            app.PreprocesamientoTab.Title = 'Preprocesamiento';
            app.UIAxes = uiaxes(app.PreprocesamientoTab);
            title(app.UIAxes, 'Señal filtrada')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.PreprocesamientoTab);
            title(app.UIAxes2, 'Señal capturada')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.PreprocesamientoTab);
            title(app.UIAxes3, 'Dominio de frecuencia señal capturada')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.PreprocesamientoTab);
            title(app.UIAxes4, 'Dominio de frecuencia señal filtrada')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.ExtraccindecaractersticasTab = uitab(app.TabGroup);
            app.ExtraccindecaractersticasTab.Title = 'Extracción de características';
            app.ClasificacinTab = uitab(app.TabGroup);
            app.ClasificacinTab.Title = 'Clasificación';
            app.EvaluacindedesempeoTab = uitab(app.TabGroup);
            app.EvaluacindedesempeoTab.Title = 'Evaluación de desempeño';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proyecto2DSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end